classdef basic_view < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        test                matlab.ui.control.Label
        userPanel           matlab.ui.container.Panel
        Image_2             matlab.ui.control.Image
        SignedinasLabel     matlab.ui.control.Label
        lblUser             matlab.ui.control.Label
        Image3              matlab.ui.control.Image
        Image2              matlab.ui.control.Image
        Image               matlab.ui.control.Image
        SourcecameraidentificationbyHellingerdistanceLabel  matlab.ui.control.Label
        QuarrelImagesLabel  matlab.ui.control.Label
        tblImages           matlab.ui.control.Table
        btnMetadata         matlab.ui.control.Button
        tblMetadata         matlab.ui.control.Table
        btnOrigin           matlab.ui.control.Button
        txtCaptureDevice    matlab.ui.control.EditField
        btnInforme          matlab.ui.control.Button
        CapturedeviceLabel  matlab.ui.control.Label
        txtImageName        matlab.ui.control.EditField
        btnLoadImage        matlab.ui.control.Button
        ImageLabel          matlab.ui.control.Label
        btnLogout           matlab.ui.control.Button
    end

    
    properties (Access = private)
        conn % Connection DB
        user % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, user)
            % Initialize general properties
            app.user = 'Test';
            
            % Show the user in the lblUser
            app.lblUser.Text = app.user;

            % Show the images in the tblImages
            app.conn = database('conn_scih', 'root', 'root');
            query = strcat("SELECT file FROM QuarrelImage WHERE idUser = 1");
            try
                rslt = fetch(app.conn, query);
            catch e
                msgbox(strcat("Error", char(e.message)), "Warning", "error");
            end

            % Initialize a data array with the correct rows
            data = cell(height(rslt),1);
            
            % Fill the data array with the info of the database
            for i=1: height(rslt)
                data(i) = cell(rslt(i,:).file);
            end

            % Fill the table with the data
            app.tblImages.Data = data;
        end

        % Button pushed function: btnLogout
        function btnLogoutButtonPushed(app, event)
            login();
            delete(app);
        end

        % Cell selection callback: tblImages
        function tblImagesCellSelection(app, event)
            indices = event.Indices;
%             app.test.Text = string(indices);

            app.txtImageName.Value = string(app.tblImages.Data(indices(1),1));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.btnLogout = uibutton(app.UIFigure, 'push');
            app.btnLogout.ButtonPushedFcn = createCallbackFcn(app, @btnLogoutButtonPushed, true);
            app.btnLogout.Text = 'Log Out';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.btnLoadImage = uibutton(app.UIFigure, 'push');
            app.btnLoadImage.Text = 'Load image';
            app.txtImageName = uieditfield(app.UIFigure, 'text');
            app.txtImageName.Editable = 'off';
            app.CapturedeviceLabel = uilabel(app.UIFigure);
            app.CapturedeviceLabel.Text = 'Capture device';
            app.btnInforme = uibutton(app.UIFigure, 'push');
            app.btnInforme.Text = 'Report';
            app.txtCaptureDevice = uieditfield(app.UIFigure, 'text');
            app.txtCaptureDevice.Editable = 'off';
            app.btnOrigin = uibutton(app.UIFigure, 'push');
            app.btnOrigin.Text = 'Origin';
            app.tblMetadata = uitable(app.UIFigure);
            app.tblMetadata.ColumnName = '';
            app.tblMetadata.RowName = {};
            app.btnMetadata = uibutton(app.UIFigure, 'push');
            app.btnMetadata.Text = 'Metadata';
            app.tblImages = uitable(app.UIFigure);
            app.tblImages.ColumnName = {'Select Image'};
            app.tblImages.RowName = {};
            app.tblImages.CellSelectionCallback = createCallbackFcn(app, @tblImagesCellSelection, true);
            app.QuarrelImagesLabel = uilabel(app.UIFigure);
            app.QuarrelImagesLabel.Text = 'Quarrel Images';
            app.SourcecameraidentificationbyHellingerdistanceLabel = uilabel(app.UIFigure);
            app.SourcecameraidentificationbyHellingerdistanceLabel.Text = 'Source camera identification by Hellinger distance';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            app.userPanel = uipanel(app.UIFigure);
            app.lblUser = uilabel(app.userPanel);
            app.lblUser.Text = 'User Admin';
            app.SignedinasLabel = uilabel(app.userPanel);
            app.SignedinasLabel.Text = 'Signed in as: ';
            app.Image_2 = uiimage(app.userPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.test = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basic_view(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end