classdef user_register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UsernameEditFieldLabel  matlab.ui.control.Label
        lblUser                 matlab.ui.control.Label
        btnBack                 matlab.ui.control.Button
        EmailEditFieldLabel     matlab.ui.control.Label
        FullnameEditFieldLabel  matlab.ui.control.Label
        lblTitle                matlab.ui.control.Label
        btnHDL                  matlab.ui.control.Button
        btnRegister             matlab.ui.control.Button
        bgUserType              matlab.ui.container.ButtonGroup
        opAdmin                 matlab.ui.control.RadioButton
        opBasic                 matlab.ui.control.RadioButton
        txtName                 matlab.ui.control.EditField
        txtEmail                matlab.ui.control.EditField
        txtPassword             matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        txtUsername             matlab.ui.control.EditField
    end

    
    properties (Access = private)
        user % Description
        username % Description
        password % Description
        email % Description
        name % Description
        type % Description
        newAdmin % Description
        conn % Connector ODBC
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, user)
            app.user = user;
            app.lblUser.Text  = app.user;
            app.conn = database('conn_scih', 'root', 'root');
        end

        % Button pushed function: btnBack
        function btnBackButtonPushed(app, event)
            admin_view(app.user);
            delete(app);
        end

        % Button pushed function: btnRegister
        function btnRegisterButtonPushed(app, event)
            app.username = app.txtUsername.Value;
            app.password = app.txtPassword.Value;
            app.email = app.txtEmail.Value;
            app.name = app.txtName.Value;

            if app.txtUsername.Value == null || app.txtPassword.Value == null || app.txtEmail.Value == null || app.txtName.Value == null
                msgbox("Rellena los perro", "Warning", "error");
            else 
                if app.opAdmin.Value
                    app.type = "admin";
                else 
                    app.type = "basic";
                end
                
                app.newAdmin = table(string(app.username), string(app.password), string(app.type), string(app.name), string(app.email), ...
                'VariableNames', {'username', 'password', 'type', 'name', 'email'});
                try 
                    sqlwrite(app.conn, 'User', app.newAdmin);
                    msgbox("User register succesfull", "Information", "help");
                catch e
                   msgbox(strcat("Error", char(e.message)), "Warnign", "error");
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.txtUsername = uieditfield(app.UIFigure, 'text');
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.txtPassword = uieditfield(app.UIFigure, 'text');
            app.txtEmail = uieditfield(app.UIFigure, 'text');
            app.txtName = uieditfield(app.UIFigure, 'text');
            app.bgUserType = uibuttongroup(app.UIFigure);
            app.bgUserType.Title = 'User type';
            app.opBasic = uiradiobutton(app.bgUserType);
            app.opBasic.Text = 'Basic';
            app.opAdmin = uiradiobutton(app.bgUserType);
            app.opAdmin.Text = 'Admin';
            app.opAdmin.Value = true;
            app.btnRegister = uibutton(app.UIFigure, 'push');
            app.btnRegister.ButtonPushedFcn = createCallbackFcn(app, @btnRegisterButtonPushed, true);
            app.btnRegister.Text = 'Register';
            app.btnHDL = uibutton(app.UIFigure, 'push');
            app.btnHDL.Text = 'HLD';
            app.lblTitle = uilabel(app.UIFigure);
            app.lblTitle.Text = 'User register';
            app.FullnameEditFieldLabel = uilabel(app.UIFigure);
            app.FullnameEditFieldLabel.Text = 'Full name';
            app.EmailEditFieldLabel = uilabel(app.UIFigure);
            app.EmailEditFieldLabel.Text = 'E-mail';
            app.btnBack = uibutton(app.UIFigure, 'push');
            app.btnBack.ButtonPushedFcn = createCallbackFcn(app, @btnBackButtonPushed, true);
            app.btnBack.Text = 'Back';
            app.lblUser = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = user_register(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end